classdef Slidip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlidipUIFigure              matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        PixelStepSpinnerLabel       matlab.ui.control.Label
        PixelStepSpinner            matlab.ui.control.Spinner
        AngularStepRadSpinnerLabel  matlab.ui.control.Label
        AngularStepRadSpinner       matlab.ui.control.Spinner
        SobelthresholdSpinnerLabel  matlab.ui.control.Label
        SobelthresholdSpinner       matlab.ui.control.Spinner
        NextButton                  matlab.ui.control.Button
        BackButton                  matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        State = 0; % This variable holds the current state
        MaxState = 4; % Description
    end
    
    methods (Access = private)
        %
        function results = executeStateUpdate(app)
            results = 0;
            switch app.State
                case 0 % no image available
                    app.UIAxes.Title = "No Image available";
                    app.BackButton.Visible = false;
                    app.SobelthresholdSpinner.Enable = false;
                    app.AngularStepRadSpinner.Enable = false;
                    app.PixelStepSpinner.Enable = false;
                    disp(app.State) 
                case 1 % image available
                    app.UIAxes.Title = "Image preview";
                    app.BackButton.Visible = true;
                    app.SobelthresholdSpinner.Enable = true;
                    app.AngularStepRadSpinner.Enable = false;
                    app.PixelStepSpinner.Enable = false;
                    disp(app.State)
                case 2 % sobel
                    app.UIAxes.Title = "Sobel filter";
                    app.SobelthresholdSpinner.Enable = true;
                    app.AngularStepRadSpinner.Enable = true;
                    app.PixelStepSpinner.Enable = false;
                    disp(app.State)
                case 3 % hough trafo                    
                    app.UIAxes.Title = "Hough transformation";
                    app.SobelthresholdSpinner.Enable = true;
                    app.AngularStepRadSpinner.Enable = true;
                    app.PixelStepSpinner.Enable = true;
                    app.NextButton.Visible = true;
                    disp(app.State)
                case 4 % reverse hogh trafo                    
                    app.UIAxes.Title = "Hough transformation overlay";
                    disp(app.State)
                    app.NextButton.Visible = false;
                otherwise
                    results = -1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AppStatup(app)
            app.executeStateUpdate()
        end

        % Button pushed function: NextButton
        function BNextPushed(app, event)
            if app.State < app.MaxState
                app.State = app.State + 1;
            end
            app.executeStateUpdate();
        end

        % Button pushed function: BackButton
        function BBackPushed(app, event)
            if app.State > 0
                app.State = app.State - 1;
            end
            app.executeStateUpdate();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SlidipUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlidipUIFigure = uifigure('Visible', 'off');
            app.SlidipUIFigure.Name = 'Slidip';
            app.SlidipUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SlidipUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @BNextPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.GridLayout2, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BBackPushed, true);
            app.BackButton.Text = 'Back';
            app.SobelthresholdSpinnerLabel = uilabel(app.GridLayout2);
            app.SobelthresholdSpinnerLabel.Text = 'Sobel threshold';
            app.SobelthresholdSpinner = uispinner(app.GridLayout2);
            app.SobelthresholdSpinner.Step = 0.01;
            app.SobelthresholdSpinner.Limits = [0 1];
            app.SobelthresholdSpinner.ValueDisplayFormat = '%.2f';
            app.AngularStepRadSpinnerLabel = uilabel(app.GridLayout2);
            app.AngularStepRadSpinnerLabel.Text = 'Angular Step [Rad]';
            app.AngularStepRadSpinner = uispinner(app.GridLayout2);
            app.AngularStepRadSpinner.Step = 0.01;
            app.AngularStepRadSpinner.Limits = [0 1];
            app.AngularStepRadSpinner.ValueDisplayFormat = '%.2f';
            app.PixelStepSpinnerLabel = uilabel(app.GridLayout2);
            app.PixelStepSpinnerLabel.Text = 'Pixel Step';
            app.PixelStepSpinner = uispinner(app.GridLayout2);
            app.PixelStepSpinner.Limits = [0 100];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.SlidipUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Slidip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlidipUIFigure)

            % Execute the startup function
            runStartupFcn(app, @AppStatup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlidipUIFigure)
        end
    end
end